classdef Anser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure  matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SetupTab                       matlab.ui.container.Tab
        ChannelselectPanel             matlab.ui.container.Panel
        ChannelSelectListBox           matlab.ui.control.ListBox
        ChannelSelectLabel             matlab.ui.control.Label
        SystemConfigurationPanel       matlab.ui.container.Panel
        SystemVersionButtonGroup       matlab.ui.container.ButtonGroup
        Anser7x7Button                 matlab.ui.control.RadioButton
        Anser9x9Button                 matlab.ui.control.RadioButton
        DuploButton                    matlab.ui.control.RadioButton
        DAQversionButtonGroup          matlab.ui.container.ButtonGroup
        NI621XButton                   matlab.ui.control.RadioButton
        MCC1608GXButton                matlab.ui.control.RadioButton
        BoardIDPanel                   matlab.ui.container.Panel
        BoardIDEditField               matlab.ui.control.EditField
        BoardIDLabel                   matlab.ui.control.Label
        ConnectSystemButton            matlab.ui.control.Button
        ConnectSystemStatus            matlab.ui.control.TextArea
        AdvancedSystemSettingPanel     matlab.ui.container.Panel
        SamplesizeSpinnerLabel         matlab.ui.control.Label
        SamplesizeSpinner              matlab.ui.control.Spinner
        CalibrationTab                 matlab.ui.container.Tab
        CalibrationsettingsPanel       matlab.ui.container.Panel
        SensorselectDropDown_4Label    matlab.ui.control.Label
        SensorSelectCalibrateDropDown  matlab.ui.control.DropDown
        CalibrateButton                matlab.ui.control.Button
        RecalibratePanel               matlab.ui.container.Panel
        ZadjustPos                     matlab.ui.control.Button
        ZadjustNeg                     matlab.ui.control.Button
        TestpointHeight01mmLabel       matlab.ui.control.Label
        RecalibrateButton              matlab.ui.control.Button
        CalibrationstatusPanel         matlab.ui.container.Panel
        CalibrationStatus              matlab.ui.control.TextArea
        TrackingTab                    matlab.ui.container.Tab
        TrackingconfigurationPanel     matlab.ui.container.Panel
        TargetspeedHzSpinnerLabel      matlab.ui.control.Label
        TargetSpeedHzSpinner           matlab.ui.control.Spinner
        StartTrackingButton            matlab.ui.control.StateButton
        StatusTextAreaLabel            matlab.ui.control.Label
        StatusTextArea                 matlab.ui.control.TextArea
        StopTrackingButton             matlab.ui.control.Button
        TrackinginformationPanel       matlab.ui.container.Panel
        TrackingFrequency              matlab.ui.control.NumericEditField
        SensorselectDropDown_7Label    matlab.ui.control.Label
        SensorSelectTrackView          matlab.ui.control.DropDown
        XmmEditFieldLabel              matlab.ui.control.Label
        XmmEditField                   matlab.ui.control.NumericEditField
        YmmEditFieldLabel              matlab.ui.control.Label
        YmmEditField                   matlab.ui.control.NumericEditField
        ZmmEditFieldLabel              matlab.ui.control.Label
        ZmmEditField                   matlab.ui.control.NumericEditField
        radiansEditFieldLabel          matlab.ui.control.Label
        RadiansThetaEditField          matlab.ui.control.NumericEditField
        radiansEditField_2Label        matlab.ui.control.Label
        RadiansPhiEditField            matlab.ui.control.NumericEditField
        TrackingFrequencyHzLabel       matlab.ui.control.Label
        OpenIGTLinkTab                 matlab.ui.container.Tab
        NetworkSettingsPanel           matlab.ui.container.Panel
        AddressEditFieldLabel          matlab.ui.control.Label
        AddressEditField               matlab.ui.control.EditField
        PortEditFieldLabel             matlab.ui.control.Label
        PortEditField                  matlab.ui.control.NumericEditField
        StreamButton                   matlab.ui.control.Button
        ConnectIGTButton               matlab.ui.control.StateButton
        ConnectionstatusPanel          matlab.ui.container.Panel
        IGTStatus                      matlab.ui.control.TextArea
        ApplyTransformPanel            matlab.ui.container.Panel
        TransformMatrix                matlab.ui.control.Table
        SensorselectDropDown_8Label    matlab.ui.control.Label
        SensorSelectTransform          matlab.ui.control.DropDown
        FlipZButton                    matlab.ui.control.Button
        ToolsTab                       matlab.ui.container.Tab
        ToolsSelect                    matlab.ui.container.TabGroup
        ViewSamples                    matlab.ui.container.Tab
        ViewFFT                        matlab.ui.container.Tab
        ViewPosition                   matlab.ui.container.Tab
        AboutTab                       matlab.ui.container.Tab
        AboutTitle                     matlab.ui.control.Label
        Cite                           matlab.ui.control.Label
        Copyright                      matlab.ui.control.Label
        AuthorList                     matlab.ui.control.Label
        Acknowledgements               matlab.ui.control.Label
        AnserEMTLabel                  matlab.ui.control.Label
        WindowLabel                    matlab.ui.control.Label
    end


    properties (Access = public)
        sys = struct(); % Description
    end

    properties (Access = private)
        sensors = [] % Description
        igtlconnection = struct(); % Description
        flipvector = zeros(1, 16) % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function AnserStartupFcn(app)
            app.TransformMatrix.Data = eye(4);
        end

        % Close request function: 
        % AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure
        function AnserCloseRequest(app, event)
            app.StartTrackingButton.Value = 0;
            if isempty(fieldnames(app.igtlconnection)) == 0
                igtlDisconnect(app.igtlconnection);
            end
            delete(app);
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            % Stop tracking
            app.StartTrackingButton.Value;
            % Determine selected DAQ
            if app.NI621XButton.Value == 1
                daqtype = 'nidaq621Xoem';
            elseif app.MCC1608GXButton.Value == 1
                daqtype = 'mccdaq';
            end
            
            % Determine selected System Type
            if app.Anser7x7Button.Value == 1
                systemtype = '7x7';
            elseif app.Anser9x9Button == 1
                systemtype = '9x9';
            elseif app.DuploButton == 1
                systemtype = 'DUPLO';
            end
            
            modeltype = 'exact';
                        
            % Determine board ID
            boardid = app.BoardIDEditField.Value;
            samplesize = 5000;
            
            % Initialise system
            try
                app.sys = fSysSetup(app.sensors,systemtype, daqtype, boardid, samplesize,modeltype);
                tempstr = strcat(app.CalibrationStatus.Value,{' '},'Starting calibration, view commandline for instructions');
                app.CalibrationStatus.Value = tempstr; 
                
                app.sys = fSysSensor(app.sys, str2num(app.SensorSelectCalibrateDropDown.Value));
                app.sys = fGetCalField(app.sys);
                app.sys = fCalibrate(app.sys);
            
                rmsError = fCheck(app.sys);
                tempstr = strcat('Calibration complete, RMS Error: ',{' '},num2str(rmsError), 'mm');
                app.CalibrationStatus.Value = tempstr;
            
                app.sys = fSysSave(app.sys);
            catch error
                app.CalibrationStatus.Value = error; 
            end    
        end

        % Value changed function: ConnectIGTButton
        function ConnectIGTButtonValueChanged(app, event)
            value = app.ConnectIGTButton.Value;
            if value == 1
                try
                    app.igtlconnection = igtlConnect(app.AddressEditField.Value, app.PortEditField.Value);
                    app.IGTStatus.Value = 'Connection Success!';
                    app.TransformMatrix.Data = eye(4);
                    app.ConnectIGTButton.Text = 'Disconnect';
                catch
                    app.IGTStatus.Value = 'Connection Failed';
                    app.ConnectIGTButton.Value = 0;
                end      
            else
                igtlDisconnect(app.igtlconnection);
                app.igtlconnection = struct();
                app.IGTStatus.Value = 'Connection Closed';
                app.ConnectIGTButton.Text = 'Connect';
            end
            
            
        end

        % Button pushed function: ConnectSystemButton
        function ConnectSystemButtonPushed(app, event)
            % Stop tracking;
            app.StartTrackingButton.Value = 0;
            
            chlist = cell2mat(app.ChannelSelectListBox.Value)';
            chlist = str2num(chlist);
            chlist_string = num2str(chlist);
            app.ConnectSystemStatus.Value = strcat('Channels selected: ',{' '},strjoin(app.ChannelSelectListBox.Value,', '));
            
            % Determine selected DAQ
            if app.NI621XButton.Value == 1
                daqtype = 'nidaq621Xoem';
            elseif app.MCC1608GXButton.Value == 1
                daqtype = 'mccdaq';
            end
            
            % Determine selected System Type
            if app.Anser7x7Button.Value == 1
                systemtype = '7x7';
            elseif app.Anser9x9Button == 1
                systemtype = '9x9';
            elseif app.DuploButton == 1
                systemtype = 'DUPLO';
            end
            
            modeltype = 'exact';
            
            % Determine board ID
            boardid = app.BoardIDEditField.Value;
            samplesize = app.SamplesizeSpinner.Value;
            
           
            % Initialise system
            try
                app.sys = fSysSetup(chlist,systemtype, daqtype, boardid, samplesize, modeltype);
                % Save sensor list locally
                app.sensors = app.sys.Sensors;
                if length(app.sensors) >= 1
                    app.StartTrackingButton.Enable = 'on';
                end
                tempstr = app.ConnectSystemStatus.Value;
                tempstr = strcat(app.ConnectSystemStatus.Value,{' '},'Success!');
                app.ConnectSystemStatus.Value = tempstr; 
            catch
                app.ConnectSystemStatus.Value = 'Error initialising system'; 
            end


        end

        % Button pushed function: FlipZButton
        function FlipZButtonPushed(app, event)
            if app.flipvector(str2num(app.SensorSelectTransform.Value)) == 0
                app.flipvector(str2num(app.SensorSelectTransform.Value)) = pi;
            else
                app.flipvector(str2num(app.SensorSelectTransform.Value)) = 0;
            end
        end

        % Button pushed function: RecalibrateButton
        function RecalibrateButtonPushed(app, event)
            app.sys = fCalibrate(app.sys);
            
            rmsError = fCheck(app.sys);
            tempstr = strcat('Calibration complete, RMS Error: ',{' '},num2str(rmsError), 'mm');
            app.CalibrationStatus.Value = tempstr;
        
            app.sys = fSysSave(app.sys);
        end

        % Value changed function: SensorSelectCalibrateDropDown
        function SensorSelectCalibrateDropDownValueChanged(app, event)
            value = app.SensorSelectCalibrateDropDown.Value;
            if isprop(app, 'sensors')
                if ismember(str2num(value),app.sys.Sensors)
                    app.CalibrateButton.Enable = 'on';
                    app.RecalibrateButton.Enable = 'on';
                else
                    app.CalibrateButton.Enable = 'off';
                    app.RecalibrateButton.Enable = 'off';
                end
            end
        end

        % Value changed function: SensorSelectTrackView
        function SensorSelectTrackViewValueChanged(app, event)
            value = app.SensorSelectTrackView.Value;
            app.XmmEditField.Value = 0;
            app.YmmEditField.Value = 0;
            app.ZmmEditField.Value = 0;
            app.RadiansThetaEditField.Value  = 0;
            app.RadiansPhiEditField.Value = 0;
        end

        % Callback function
        function SensorselectDropDown_2ValueChanged(app, event)
            value = app.SensorselectDropDown_2.Value;
            if isprop(app, 'sensors')
                if ismember(str2num(value),app.sys.Sensors)
                    app.AcquireButton.Enable = 'on';
                else
                    app.AcquireButton.Enable = 'off';
                end
            end
        end

        % Value changed function: StartTrackingButton
        function StartTrackingButtonValueChanged(app, event)
            value = app.StartTrackingButton.Value;
            app.StartTrackingButton.Enable = 'off';
            app.StopTrackingButton.Enable = 'on';
            
            sensorPositions = zeros(length(app.sensors),5);
            sensorMatrix    = zeros(4,4,length(app.sensors));
            
            while app.StartTrackingButton.Value == 1
               tStart = tic;
               
               app.sys = fSysDAQUpdate(app.sys);
               
               for i=1:length(app.sensors)
                   app.sys = fGetSensorPosition(app.sys,app.sensors(i));
                   sensorPositions(i,:) = app.sys.positionVector;
                   sensorPositions(i,4) = sensorPositions(i,4) + app.flipvector(i);
                   sensorMatrix(:,:,i) = fSphericalToMatrix(sensorPositions);
                   
                   if app.sensors(i) == str2num(app.SensorSelectTrackView.Value)
                       app.XmmEditField.Value = sensorPositions(i,1)*1000;
                       app.YmmEditField.Value = sensorPositions(i,2)*1000;
                       app.ZmmEditField.Value = sensorPositions(i,3)*1000;
                       app.RadiansThetaEditField.Value  = sensorPositions(i,4);
                       app.RadiansPhiEditField.Value = sensorPositions(i,5);
                   end   
               end
               
               if isempty(fieldnames(app.igtlconnection)) == 0
                   transform = struct();
                   for i = 1:length(app.sensors)
                       transform.name = strcat('ProbeToTracker',num2str(app.sensors(i)));
                       transform.timestamp = igtlTimestampNow();
                       transform.matrix = sensorMatrix(:,:,i);
                       transform.matrix(1:3,4) = transform.matrix(1:3,4)*1000;
                       igtlSendTransform(app.igtlconnection,transform);
                   end
               end
            
               pause(1/app.TargetSpeedHzSpinner.Value);
               tStop = toc(tStart);
               tFreq = 1/tStop;
            
               app.TrackingFrequency.Value = tFreq;
            end
            
            app.TrackingFrequency.Value = 0;
            
        end

        % Button pushed function: StopTrackingButton
        function StopTrackingButtonPushed(app, event)
            app.StartTrackingButton.Value = 0;
            app.StartTrackingButton.Enable = 'on';
            app.StopTrackingButton.Enable = 'off';
        end

        % Button pushed function: ZadjustNeg
        function ZadjustNegPushed(app, event)
            app.sys.ztestpoint = app.sys.ztestpoint - 0.0001;
            app.CalibrationStatus.Value = strcat('Test point height:',{' '},num2str(app.sys.ztestpoint(1)*1000));
        end

        % Button pushed function: ZadjustPos
        function ZadjustPosPushed(app, event)
            app.sys.ztestpoint = app.sys.ztestpoint + 0.0001;
            app.CalibrationStatus.Value = strcat('Test point height:',{' '},num2str(app.sys.ztestpoint(1)*1000));
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure = uifigure;
            app.AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure.Name = 'Anser EMT: An open source electromagnetic tracking platform';
            app.AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure.CloseRequestFcn = createCallbackFcn(app, @AnserCloseRequest, true);
            app.TabGroup = uitabgroup(app.AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.ChannelselectPanel = uipanel(app.SetupTab);
            app.ChannelselectPanel.Title = 'Channel select';
            app.ChannelSelectListBox = uilistbox(app.ChannelselectPanel);
            app.ChannelSelectListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.ChannelSelectListBox.Multiselect = 'on';
            app.ChannelSelectListBox.Value = {'1'};
            app.ChannelSelectLabel = uilabel(app.ChannelselectPanel);
            app.ChannelSelectLabel.Text = {'Hold <Ctrl> to select'; 'multiple channels'};
            app.SystemConfigurationPanel = uipanel(app.SetupTab);
            app.SystemConfigurationPanel.Title = 'System configuration';
            app.SystemVersionButtonGroup = uibuttongroup(app.SystemConfigurationPanel);
            app.SystemVersionButtonGroup.Title = 'System version';
            app.Anser7x7Button = uiradiobutton(app.SystemVersionButtonGroup);
            app.Anser7x7Button.Text = 'Anser 7x7';
            app.Anser7x7Button.Value = true;
            app.Anser9x9Button = uiradiobutton(app.SystemVersionButtonGroup);
            app.Anser9x9Button.Text = 'Anser 9x9';
            app.DuploButton = uiradiobutton(app.SystemVersionButtonGroup);
            app.DuploButton.Text = 'Duplo';
            app.DAQversionButtonGroup = uibuttongroup(app.SystemConfigurationPanel);
            app.DAQversionButtonGroup.Title = 'DAQ version';
            app.NI621XButton = uiradiobutton(app.DAQversionButtonGroup);
            app.NI621XButton.Text = 'NI-621X';
            app.NI621XButton.Value = true;
            app.MCC1608GXButton = uiradiobutton(app.DAQversionButtonGroup);
            app.MCC1608GXButton.Text = 'MCC 1608GX';
            app.BoardIDPanel = uipanel(app.SystemConfigurationPanel);
            app.BoardIDPanel.Title = 'Board ID';
            app.BoardIDEditField = uieditfield(app.BoardIDPanel, 'text');
            app.BoardIDEditField.Value = 'Dev1';
            app.BoardIDLabel = uilabel(app.BoardIDPanel);
            app.BoardIDLabel.Text = '(eg. Dev1, Board1 etc.)';
            app.ConnectSystemButton = uibutton(app.SystemConfigurationPanel, 'push');
            app.ConnectSystemButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectSystemButtonPushed, true);
            app.ConnectSystemButton.Text = 'Connect';
            app.ConnectSystemStatus = uitextarea(app.SystemConfigurationPanel);
            app.ConnectSystemStatus.Editable = 'off';
            app.AdvancedSystemSettingPanel = uipanel(app.SystemConfigurationPanel);
            app.AdvancedSystemSettingPanel.Title = 'Advanced';
            app.SamplesizeSpinnerLabel = uilabel(app.AdvancedSystemSettingPanel);
            app.SamplesizeSpinnerLabel.Text = 'Sample size';
            app.SamplesizeSpinner = uispinner(app.AdvancedSystemSettingPanel);
            app.SamplesizeSpinner.Step = 100;
            app.SamplesizeSpinner.Limits = [500 5000];
            app.SamplesizeSpinner.Value = 1000;
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.CalibrationsettingsPanel = uipanel(app.CalibrationTab);
            app.CalibrationsettingsPanel.Title = 'Calibration settings';
            app.SensorselectDropDown_4Label = uilabel(app.CalibrationsettingsPanel);
            app.SensorselectDropDown_4Label.Text = 'Sensor select';
            app.SensorSelectCalibrateDropDown = uidropdown(app.CalibrationsettingsPanel);
            app.SensorSelectCalibrateDropDown.Items = {'Select', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.SensorSelectCalibrateDropDown.ValueChangedFcn = createCallbackFcn(app, @SensorSelectCalibrateDropDownValueChanged, true);
            app.SensorSelectCalibrateDropDown.Value = 'Select';
            app.CalibrateButton = uibutton(app.CalibrationsettingsPanel, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Enable = 'off';
            app.CalibrateButton.Text = 'Calibrate';
            app.RecalibratePanel = uipanel(app.CalibrationsettingsPanel);
            app.RecalibratePanel.Title = 'Advanced';
            app.ZadjustPos = uibutton(app.RecalibratePanel, 'push');
            app.ZadjustPos.ButtonPushedFcn = createCallbackFcn(app, @ZadjustPosPushed, true);
            app.ZadjustPos.Text = '+';
            app.ZadjustNeg = uibutton(app.RecalibratePanel, 'push');
            app.ZadjustNeg.ButtonPushedFcn = createCallbackFcn(app, @ZadjustNegPushed, true);
            app.ZadjustNeg.Text = '-';
            app.TestpointHeight01mmLabel = uilabel(app.RecalibratePanel);
            app.TestpointHeight01mmLabel.Text = {'Testpoint height'; '(0.1mm)'};
            app.RecalibrateButton = uibutton(app.RecalibratePanel, 'push');
            app.RecalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @RecalibrateButtonPushed, true);
            app.RecalibrateButton.Enable = 'off';
            app.RecalibrateButton.Text = 'Recalibrate';
            app.CalibrationstatusPanel = uipanel(app.CalibrationTab);
            app.CalibrationstatusPanel.Title = 'Calibration status';
            app.CalibrationStatus = uitextarea(app.CalibrationstatusPanel);
            app.CalibrationStatus.Editable = 'off';
            app.TrackingTab = uitab(app.TabGroup);
            app.TrackingTab.Title = 'Tracking';
            app.TrackingconfigurationPanel = uipanel(app.TrackingTab);
            app.TrackingconfigurationPanel.Title = 'Tracking configuration';
            app.TargetspeedHzSpinnerLabel = uilabel(app.TrackingconfigurationPanel);
            app.TargetspeedHzSpinnerLabel.Text = 'Target speed (Hz)';
            app.TargetSpeedHzSpinner = uispinner(app.TrackingconfigurationPanel);
            app.TargetSpeedHzSpinner.Step = 5;
            app.TargetSpeedHzSpinner.Limits = [1 200];
            app.TargetSpeedHzSpinner.Value = 30;
            app.StartTrackingButton = uibutton(app.TrackingconfigurationPanel, 'state');
            app.StartTrackingButton.ValueChangedFcn = createCallbackFcn(app, @StartTrackingButtonValueChanged, true);
            app.StartTrackingButton.Enable = 'off';
            app.StartTrackingButton.Text = 'Start tracking';
            app.StatusTextAreaLabel = uilabel(app.TrackingconfigurationPanel);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.TrackingconfigurationPanel);
            app.StopTrackingButton = uibutton(app.TrackingconfigurationPanel, 'push');
            app.StopTrackingButton.ButtonPushedFcn = createCallbackFcn(app, @StopTrackingButtonPushed, true);
            app.StopTrackingButton.Enable = 'off';
            app.StopTrackingButton.Text = 'Stop tracking';
            app.TrackinginformationPanel = uipanel(app.TrackingTab);
            app.TrackinginformationPanel.Title = 'Tracking information';
            app.TrackingFrequency = uieditfield(app.TrackinginformationPanel, 'numeric');
            app.TrackingFrequency.ValueDisplayFormat = '%.0f';
            app.TrackingFrequency.Editable = 'off';
            app.SensorselectDropDown_7Label = uilabel(app.TrackinginformationPanel);
            app.SensorselectDropDown_7Label.Text = 'Sensor select';
            app.SensorSelectTrackView = uidropdown(app.TrackinginformationPanel);
            app.SensorSelectTrackView.Items = {'Select', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.SensorSelectTrackView.ValueChangedFcn = createCallbackFcn(app, @SensorSelectTrackViewValueChanged, true);
            app.SensorSelectTrackView.Value = 'Select';
            app.XmmEditFieldLabel = uilabel(app.TrackinginformationPanel);
            app.XmmEditFieldLabel.Text = 'X (mm)';
            app.XmmEditField = uieditfield(app.TrackinginformationPanel, 'numeric');
            app.XmmEditField.Editable = 'off';
            app.YmmEditFieldLabel = uilabel(app.TrackinginformationPanel);
            app.YmmEditFieldLabel.Text = 'Y (mm)';
            app.YmmEditField = uieditfield(app.TrackinginformationPanel, 'numeric');
            app.YmmEditField.Editable = 'off';
            app.ZmmEditFieldLabel = uilabel(app.TrackinginformationPanel);
            app.ZmmEditFieldLabel.Text = 'Z (mm)';
            app.ZmmEditField = uieditfield(app.TrackinginformationPanel, 'numeric');
            app.ZmmEditField.Editable = 'off';
            app.radiansEditFieldLabel = uilabel(app.TrackinginformationPanel);
            app.radiansEditFieldLabel.Text = 'θ (radians)';
            app.RadiansThetaEditField = uieditfield(app.TrackinginformationPanel, 'numeric');
            app.RadiansThetaEditField.Editable = 'off';
            app.radiansEditField_2Label = uilabel(app.TrackinginformationPanel);
            app.radiansEditField_2Label.Text = 'φ (radians)';
            app.RadiansPhiEditField = uieditfield(app.TrackinginformationPanel, 'numeric');
            app.RadiansPhiEditField.Editable = 'off';
            app.TrackingFrequencyHzLabel = uilabel(app.TrackinginformationPanel);
            app.TrackingFrequencyHzLabel.Text = 'Tracking frequency (Hz)';
            app.OpenIGTLinkTab = uitab(app.TabGroup);
            app.OpenIGTLinkTab.Title = 'OpenIGTLink';
            app.NetworkSettingsPanel = uipanel(app.OpenIGTLinkTab);
            app.NetworkSettingsPanel.Title = 'Network settings';
            app.AddressEditFieldLabel = uilabel(app.NetworkSettingsPanel);
            app.AddressEditFieldLabel.Text = 'Address';
            app.AddressEditField = uieditfield(app.NetworkSettingsPanel, 'text');
            app.AddressEditField.Value = '127.0.0.1';
            app.PortEditFieldLabel = uilabel(app.NetworkSettingsPanel);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.NetworkSettingsPanel, 'numeric');
            app.PortEditField.ValueDisplayFormat = '%.0f';
            app.PortEditField.Value = 18944;
            app.StreamButton = uibutton(app.NetworkSettingsPanel, 'push');
            app.StreamButton.Enable = 'off';
            app.StreamButton.Text = 'Stream';
            app.ConnectIGTButton = uibutton(app.NetworkSettingsPanel, 'state');
            app.ConnectIGTButton.ValueChangedFcn = createCallbackFcn(app, @ConnectIGTButtonValueChanged, true);
            app.ConnectIGTButton.Text = 'Connect';
            app.ConnectionstatusPanel = uipanel(app.OpenIGTLinkTab);
            app.ConnectionstatusPanel.Title = 'Connection status';
            app.IGTStatus = uitextarea(app.ConnectionstatusPanel);
            app.IGTStatus.Editable = 'off';
            app.ApplyTransformPanel = uipanel(app.OpenIGTLinkTab);
            app.ApplyTransformPanel.Title = 'Apply transform';
            app.TransformMatrix = uitable(app.ApplyTransformPanel);
            app.TransformMatrix.ColumnName = {'x'; 'y'; 'z'; 't'};
            app.TransformMatrix.RowName = {};
            app.TransformMatrix.ColumnEditable = true;
            app.SensorselectDropDown_8Label = uilabel(app.ApplyTransformPanel);
            app.SensorselectDropDown_8Label.Text = 'Sensor select';
            app.SensorSelectTransform = uidropdown(app.ApplyTransformPanel);
            app.SensorSelectTransform.Items = {'Select', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.SensorSelectTransform.Value = 'Select';
            app.FlipZButton = uibutton(app.ApplyTransformPanel, 'push');
            app.FlipZButton.ButtonPushedFcn = createCallbackFcn(app, @FlipZButtonPushed, true);
            app.FlipZButton.Text = 'Flip Z';
            app.ToolsTab = uitab(app.TabGroup);
            app.ToolsTab.Title = 'Tools';
            app.ToolsSelect = uitabgroup(app.ToolsTab);
            app.ViewSamples = uitab(app.ToolsSelect);
            app.ViewSamples.Title = 'Sample';
            app.ViewFFT = uitab(app.ToolsSelect);
            app.ViewFFT.Title = 'FFT';
            app.ViewPosition = uitab(app.ToolsSelect);
            app.ViewPosition.Title = 'Position';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.AboutTitle = uilabel(app.AboutTab);
            app.AboutTitle.Text = {'Anser EMT: An open source electromagnetic tracking platform'; ''};
            app.Cite = uilabel(app.AboutTab);
            app.Cite.Text = {'To acknowledge Anser EMT as a platform, please cite the OpenEMT website '; 'and the following publications when publishing work that uses or incorporates Anser EMT:'};
            app.Copyright = uilabel(app.AboutTab);
            app.Copyright.Text = {'Copyright (c) 2017, H. A. Jaeger, K. O'' Donoghue'; 'All rights reserved.'; 'This code is licensed under the BSD 3-Clause License.'; ''; ''};
            app.AuthorList = uilabel(app.AboutTab);
            app.AuthorList.Text = {'Jaeger H.A., Franz A.M., O'' Donoghue K., Seitel., Trauzettel F., Maier-Hein L., Cantillon-Murphy P.'; '"Anser EMT: the first open-source electromagnetic tracking platform for image-guided interventions".'; 'International Journal of Computer Assisted Radiology and Surgery, June 2017, 12(6):1059–1067.'; 'PMID: 28357627, DOI: 10.1007/s11548-017-1568-7'; ''};
            app.Acknowledgements = uilabel(app.AboutTab);
            app.Acknowledgements.Text = {'This work was supported by the Irish Health Research Board, Science Foundation Ireland'; 'and the New Horizon Framework Programme Grant Agreement (details in paper)'; 'The authors would also like to acknowledge the German Cancer Research Center (DKFZ), Heidelberg'; 'and the Institute of Image-Guided Surgery (IHU), Strasbourg in supporting this work.'};
            app.AnserEMTLabel = uilabel(app.AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure);
            app.AnserEMTLabel.Text = 'Anser EMT';
            app.WindowLabel = uilabel(app.AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure);
            app.WindowLabel.Text = 'A graphical user interface for the Anser EMT platform.';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Anser

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure)

            % Execute the startup function
            runStartupFcn(app, @AnserStartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure)
        end
    end
end